classdef PupilAdjustment < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ChangeLeftEyeCornerButton   matlab.ui.control.Button
        ChangeRightEyeCornerButton  matlab.ui.control.Button
        DividerThresholdLabel       matlab.ui.control.Label
        DividerThresholdEditField   matlab.ui.control.NumericEditField
        GaussianEditFieldLabel      matlab.ui.control.Label
        GaussianEditField           matlab.ui.control.NumericEditField
        NextButton                  matlab.ui.control.Button
        FinishTrialButton           matlab.ui.control.Button
        SelectDataPathButton        matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        TrialEditFieldLabel         matlab.ui.control.Label
        TrialEditField              matlab.ui.control.NumericEditField
        SideButtonGroup             matlab.ui.container.ButtonGroup
        LeftButton                  matlab.ui.control.RadioButton
        RightButton                 matlab.ui.control.RadioButton
        MethodButtonGroup           matlab.ui.container.ButtonGroup
        OriginalButton              matlab.ui.control.RadioButton
        NewButton                   matlab.ui.control.RadioButton
        ManualButton                matlab.ui.control.Button
        rangeEditFieldLabel         matlab.ui.control.Label
        rangeEditField              matlab.ui.control.NumericEditField
        changeEditFieldLabel        matlab.ui.control.Label
        changeEditField             matlab.ui.control.NumericEditField
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        threshold
        gaussian
        ImageEye
        eye_dlc_output
        framei
        biasx
        biasy
        Intersectpupil
        pupil_line
        pupil_to_corner_line_distance
        Intersectlight
        reflection_line
        eye_corner_line
        vL
        allpupil
        reflection
        pupil
        elipse
        pupil_mask
        Trialnumber
        tracker
        data_path
        pupilcenter
        thiscenter
        Pupil
        data_folder
        data_folder_left
        data_folder_right
        method
        pupil_function
        R
        pupilarea
    end
    
    methods (Access = public)
        
        function plot_pupil(app)
            imshow(app.pupil_mask,'Parent', app.UIAxes_2)
            title(app.UIAxes_2,'mask')
            PupilPlotter.plot_pupil_fit(app.UIAxes,app.ImageEye,app.elipse,...
                app.pupil,app.eye_dlc_output,app.framei)
        end
        
        function calculate_pupil(app)
            try
                [app.reflection,app.pupil,app.elipse,app.pupil_mask]=app.pupil_function(app.ImageEye,...
                    app.framei,app.eye_dlc_output,app.threshold,app.gaussian);
            catch
                errordlg('Invalid Parameters');
            end
        end
        
        function update_plot(app)
            app.calculate_pupil()
            app.plot_pupil()
        end
        
        function Pupil = get_new_pupil(app)
            Pupil = struct;
            Pupil.Left = struct;
            Pupil.Right = struct;
        end
        
        function changed = pupil_area_changed(app)
            thisarea =  pi.*app.pupil.MajorAxisLength.*app.pupil.MinorAxisLength;
            percentage_change = app.changeEditField.Value/100;
            changed = thisarea>app.pupilarea*(1+percentage_change)||thisarea<app.pupilarea*(1-percentage_change);
        end

        function start_trial(app)
            app.pupil_function=@ExtractEyeInfoNew;
            app.tracker = PupilTracker(app.data_path);
            if exist(fullfile(app.data_path,'Pupil.mat'))
                app.Pupil = load(fullfile(app.data_path,'Pupil.mat'),'Pupil').Pupil;
            else
                app.Pupil = app.get_new_pupil();
            end
            app.framei = 1;
            app.threshold = 15;
            app.gaussian = 10;
            app.DividerThresholdEditField.Value = app.threshold;
            app.GaussianEditField.Value =  app.gaussian;
            app.Intersectpupil=[];
            app.pupil_line=[];
            app.pupil_to_corner_line_distance=[];
            app.Intersectlight=[];
            app.reflection_line=[];
            app.eye_corner_line=[];
            app.eye_dlc_output = app.tracker.get_eye_dlc_output(app.Trialnumber);
            app.vL = VideoReader(fullfile(app.data_path,[num2str(app.Trialnumber),'EYE.avi']) );
            app.allpupil = array2table(zeros(0,4));
            app.allpupil.Properties.VariableNames = {'Centroid','MajorAxisLength', ...
                'MinorAxisLength','Orientation'};
            app.ImageEye = PupilTracker.readindex(app.vL,double(app.framei));
            app.update_plot();
        end
        
        function save_frame(app)
            app.pupilcenter(app.framei,:)=app.pupil.Centroid;
            pupil = app.pupil;
            if isfield(pupil,'R')
                pupil= rmfield(pupil,'R');
            end
            app.allpupil = [app.allpupil;struct2table(pupil)];
            shift = 4;
            cornerx = [app.eye_dlc_output.Ex1(app.framei),app.eye_dlc_output.Ex3(app.framei)]-app.biasx;
            cornery = [app.eye_dlc_output.Ey1(app.framei),app.eye_dlc_output.Ey3(app.framei)+shift]-app.biasy;
            [app.Intersectpupil(app.framei,:),app.pupil_line(app.framei,:),~,app.pupil_to_corner_line_distance(app.framei)] ...
                = findprojectedvalues (app.pupil.Centroid,cornerx,cornery);
        end
        
        function checkparam = check_adjescent_range(app)
            checkparam = 1;
            range = app.rangeEditField.Value;
            for thresholdi = app.threshold-range:app.threshold+range
                [app.reflection,app.pupil,app.elipse,app.pupil_mask]=app.pupil_function(app.ImageEye,...
                    app.framei,app.eye_dlc_output,thresholdi,app.gaussian);
                if ~app.pupil_area_changed()
                    checkparam = 0;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data_folder_left = '/net/dk-server/afassihizakeri/Leftsidemovies';
            app.data_folder_right = '/net/dk-server/afassihizakeri/rightsidemovies';
            app.method = 'Original';
            app.Trialnumber = app.TrialEditField.Value;
            app.data_folder = app.data_folder_left;
            app.biasx= -4;
            app.biasy= -2;
            app.data_path = 'Z:\william\test pupil';
        end

        % Value changed function: DividerThresholdEditField
        function DividerThresholdEditFieldValueChanged(app, event)
            app.threshold = app.DividerThresholdEditField.Value;
            app.update_plot();
        end

        % Value changed function: GaussianEditField
        function GaussianEditFieldValueChanged(app, event)
            app.gaussian = app.GaussianEditField.Value;
            app.update_plot();
        end

        % Button pushed function: ChangeLeftEyeCornerButton
        function ChangeLeftEyeCornerButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure) 
            [x, y] = ginput(1);
            app.eye_dlc_output.Ex1(app.framei) = x+app.biasx;
            app.eye_dlc_output.Ey1(app.framei) = y+app.biasy;
            app.update_plot()
        end

        % Button pushed function: ChangeRightEyeCornerButton
        function ChangeRightEyeCornerButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on') 
            set(0, 'CurrentFigure', app.UIFigure) 
            [x, y] = ginput(1);
            app.eye_dlc_output.Ex3(app.framei) = x+app.biasx;
            app.eye_dlc_output.Ey3(app.framei) = y+app.biasy;
            app.update_plot()
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            try
                if app.framei >= size(app.eye_dlc_output,1)
                    errordlg('end of trial');
                    return
                end
                checkparam =0;
                while checkparam==0
                    app.save_frame();
                    app.framei=app.framei+1;
                    app.ImageEye = PupilTracker.readindex(app.vL,double(app.framei));
                    app.calculate_pupil()
                    if app.framei>2
                        app.pupilarea= ( pi.*(app.allpupil.MinorAxisLength.*app.allpupil.MajorAxisLength));
                        app.pupilarea=mean(app.pupilarea(end-1:end));
                        if app.pupil_area_changed()
                            checkparam = app.check_adjescent_range();
                        end
                    end
                end
                app.update_plot()
            catch
                f = errordlg('error');
            end
        end

        % Button pushed function: FinishTrialButton
        function FinishTrialButtonPushed(app, event)
            set(app.TrialEditField, 'Enable', 'on')
            set(app.StartButton, 'Enable', 'on')
            set(app.SelectDataPathButton, 'Enable', 'on')
            side = app.SideButtonGroup.SelectedObject.Text;
            save_object = getfield(app.Pupil,side);
            save_object.intersectpupil{app.Trialnumber+1}=app.Intersectpupil;
            save_object.intersectlight{app.Trialnumber+1}=app.Intersectlight;
            save_object.DY{app.Trialnumber+1}=app.pupil_to_corner_line_distance;
            save_object.EYEhorizontalaxis{app.Trialnumber+1}=app.eye_corner_line;
            save_object.Pupil{app.Trialnumber+1}=app.allpupil;
            save_object.pupilcenter{app.Trialnumber+1}=app.pupilcenter;
            save_object.Light{app.Trialnumber+1}=app.thiscenter;
            save_object.TearDuct{app.Trialnumber+1}=[app.eye_dlc_output.Ex1,app.eye_dlc_output.Ey1];
            save_object.EyeBack{app.Trialnumber+1}=[app.eye_dlc_output.Ex3,app.eye_dlc_output.Ey3];
            Pupil = app.get_new_pupil();
            Pupil = setfield(Pupil,side,save_object);
            save(fullfile(app.data_path,'Pupil.mat'),'Pupil');
        end

        % Button pushed function: SelectDataPathButton
        function SelectDataPathButtonPushed(app, event)
            path = uigetdir(app.data_folder);
            if isa(path,'char')
                app.data_path = path;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                app.start_trial();
                set(app.TrialEditField, 'Enable', 'off')
                set(app.StartButton, 'Enable', 'off')
                set(app.SelectDataPathButton, 'Enable', 'off')
            catch exception
                if contains(exception.identifier,'FileNotFound')||contains(exception.identifier,'couldNotReadFile')
                    f = errordlg('not an experiment folder');
                elseif contains(exception.identifier,'badsubscript')
                    f = errordlg('not an valid trail');
                else
                    disp('unknown')
                end
            end
        end

        % Value changed function: TrialEditField
        function TrialEditFieldValueChanged(app, event)
            app.Trialnumber = app.TrialEditField.Value;
        end

        % Selection changed function: SideButtonGroup
        function SideButtonGroupSelectionChanged(app, event)
            side = app.SideButtonGroup.SelectedObject.Text;
            if strcmp(convertCharsToStrings(side),'Left')
                app.data_folder = app.data_folder_left;
            else
                app.data_folder = app.data_folder_right;
            end
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            app.method = app.MethodButtonGroup.SelectedObject.Text;
            if strcmp(convertCharsToStrings(app.method),'Original')
                app.pupil_function = @ExtractEyeInfoNew;
            else
                app.pupil_function = @extract_eye_partial;
            end
            app.plot_pupil()
        end

        % Button pushed function: ManualButton
        function ManualButtonPushed(app, event)
%             [app.reflection,app.pupil,app.elipse,app.pupil_mask]
            imshow(app.ImageEye,'Parent', app.UIAxes)
            [xi,yi] = getpts(app.UIAxes);
            [app.pupil,app.elipse,~] = PupilTracker.fit_elipse_from_polygon([xi,yi],[]);
            app.plot_pupil()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ChangeLeftEyeCornerButton = uibutton(app.UIFigure, 'push');
            app.ChangeLeftEyeCornerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeLeftEyeCornerButtonPushed, true);
            app.ChangeLeftEyeCornerButton.Text = 'Change Left Eye Corner';
            app.ChangeRightEyeCornerButton = uibutton(app.UIFigure, 'push');
            app.ChangeRightEyeCornerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRightEyeCornerButtonPushed, true);
            app.ChangeRightEyeCornerButton.Text = 'Change Right Eye Corner';
            app.DividerThresholdLabel = uilabel(app.UIFigure);
            app.DividerThresholdLabel.Text = {'Divider /'; 'Threshold'};
            app.DividerThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DividerThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DividerThresholdEditFieldValueChanged, true);
            app.DividerThresholdEditField.Value = 15;
            app.GaussianEditFieldLabel = uilabel(app.UIFigure);
            app.GaussianEditFieldLabel.Text = 'Gaussian';
            app.GaussianEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussianEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianEditFieldValueChanged, true);
            app.GaussianEditField.Value = 10;
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FinishTrialButton = uibutton(app.UIFigure, 'push');
            app.FinishTrialButton.ButtonPushedFcn = createCallbackFcn(app, @FinishTrialButtonPushed, true);
            app.FinishTrialButton.Text = 'Finish Trial';
            app.SelectDataPathButton = uibutton(app.UIFigure, 'push');
            app.SelectDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataPathButtonPushed, true);
            app.SelectDataPathButton.Text = 'Select Data Path';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrialEditFieldLabel = uilabel(app.UIFigure);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialEditField.ValueChangedFcn = createCallbackFcn(app, @TrialEditFieldValueChanged, true);
            app.TrialEditField.Value = 1;
            app.SideButtonGroup = uibuttongroup(app.UIFigure);
            app.SideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SideButtonGroupSelectionChanged, true);
            app.SideButtonGroup.Title = 'Side';
            app.LeftButton = uiradiobutton(app.SideButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SideButtonGroup);
            app.RightButton.Text = 'Right';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.OriginalButton = uiradiobutton(app.MethodButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.NewButton = uiradiobutton(app.MethodButtonGroup);
            app.NewButton.Text = 'New';
            app.ManualButton = uibutton(app.UIFigure, 'push');
            app.ManualButton.ButtonPushedFcn = createCallbackFcn(app, @ManualButtonPushed, true);
            app.ManualButton.Text = 'Manual';
            app.rangeEditFieldLabel = uilabel(app.UIFigure);
            app.rangeEditFieldLabel.Text = 'range';
            app.rangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.rangeEditField.Value = 1;
            app.changeEditFieldLabel = uilabel(app.UIFigure);
            app.changeEditFieldLabel.Text = '%change';
            app.changeEditField = uieditfield(app.UIFigure, 'numeric');
            app.changeEditField.Value = 5;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PupilAdjustment

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end