classdef PupilAdjustment_VER2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DrawPupilButton                 matlab.ui.control.Button
        FinishtrialwithoutsavingButton  matlab.ui.control.Button
        Tracked_framesEditField         matlab.ui.control.NumericEditField
        Tracked_framesEditFieldLabel    matlab.ui.control.Label
        PathEditField                   matlab.ui.control.EditField
        PathEditFieldLabel              matlab.ui.control.Label
        precentchangeEditField          matlab.ui.control.NumericEditField
        precentchangeEditFieldLabel     matlab.ui.control.Label
        MethodButtonGroup               matlab.ui.container.ButtonGroup
        NewButton                       matlab.ui.control.RadioButton
        OriginalButton                  matlab.ui.control.RadioButton
        SideButtonGroup                 matlab.ui.container.ButtonGroup
        RightButton                     matlab.ui.control.RadioButton
        LeftButton                      matlab.ui.control.RadioButton
        TrialEditField                  matlab.ui.control.NumericEditField
        TrialEditFieldLabel             matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        SelectDataPathButton            matlab.ui.control.Button
        FinishTrialButton               matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        GaussianEditField               matlab.ui.control.NumericEditField
        GaussianEditFieldLabel          matlab.ui.control.Label
        DividerThresholdEditField       matlab.ui.control.NumericEditField
        DividerThresholdLabel           matlab.ui.control.Label
        ChangeRightEyeCornerButton      matlab.ui.control.Button
        ChangeLeftEyeCornerButton       matlab.ui.control.Button
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end


    properties (Access = public)

        precentchange
        threshold
        gaussian
        ImageEye
        eye_dlc_output
        framei
        biasx
        biasy
        Intersectpupil
        pupil_line
        pupil_to_corner_line_distance
        Intersectlight
        reflection_line
        eye_corner_line
        vL
        allpupil
        reflection
        pupil
        elipse
        pupil_mask
        Trialnumber
        tracker
        data_path
        pupilcenter
        thiscenter
        Pupil
        data_folder
        data_folder_left
        data_folder_right
        method
        pupil_function
        pupil_plot
        R
    end

    methods (Access = public)

        function update_plot(app)
            try
                [app.reflection,app.pupil,app.elipse,app.pupil_mask]=app.pupil_function(app.ImageEye,...
                    app.framei,app.eye_dlc_output,app.threshold,app.gaussian);
                % try while loop here
                imshow(app.pupil_mask,'Parent', app.UIAxes_2)
                title(app.UIAxes_2,'mask')
                PupilPlotter.plot_pupil_fit(app.UIAxes,app.ImageEye,app.elipse,...
                    app.pupil,app.eye_dlc_output,app.framei)
                app.PathEditField.Value=app.data_path;
                %                       app.pupil_plot = PupilPlotter.plot_pupil(app.ImageEye,app.pupil_plot,app.UIAxes);
                %                 PupilPlotter.plot_pupil_fit(app.UIAxes,app.ImageEye,app.elipse,...
                %                     app.pupil,app.eye_dlc_output,app.framei)

            catch
                %                 errordlg('Invalid Parameters');
            end
        end
        function Pupil = get_new_pupil(app)
            Pupil = struct;
            Pupil.Left = struct;
            Pupil.Right = struct;
        end

        function start_trial(app)
            app.pupil_function=@ExtractEyeInfoNew;
            app.tracker = PupilTracker(app.data_path);

            if exist(fullfile(app.data_path,'Pupil.mat'))

                app.Pupil = load(fullfile(app.data_path,'Pupil.mat'),'Pupil').Pupil;
            else

                app.Pupil = app.get_new_pupil();
            end
%             if isfield(app.Pupil.Left,'Pupil')
%                 app.Tracked_framesEditField.Value = numel(app.Pupil.Left.Pupil{app.Trialnumber+1});
%             elseif isfield(app.Pupil.Right,'Pupil')
%                 app.Tracked_framesEditField.Value = numel(app.Pupil.Right.Pupil{app.Trialnumber+1});
%             else
%                 app.Tracked_framesEditField.Value = 0;
%             end
            app.framei = 1;
            if isempty(app.threshold )
                app.threshold = 15;
            end
            app.gaussian = 10;
            app.DividerThresholdEditField.Value = app.threshold;
            app.GaussianEditField.Value =  app.gaussian;
            app.Intersectpupil=[];
            app.pupil_line=[];
            app.pupil_to_corner_line_distance=[];
            app.Intersectlight=[];
            app.reflection_line=[];
            app.eye_corner_line=[];
            app.eye_dlc_output = app.tracker.get_eye_dlc_output(app.Trialnumber);
            app.vL = VideoReader(fullfile(app.data_path,[num2str(app.Trialnumber),'EYE.avi']) );
            app.allpupil = array2table(zeros(0,4));
            app.allpupil.Properties.VariableNames = {'Centroid','MajorAxisLength', ...
                'MinorAxisLength','Orientation'};
            app.eye_dlc_output = app.tracker.get_eye_dlc_output(app.Trialnumber);
            app.ImageEye = PupilTracker.readindex(app.vL,double(app.framei));
            app.update_plot();
            app.PathEditField.Value='';
        end

        function save_frame(app)
            %             Center=app.reflection.Centroid;
            %             Centersize=app.reflection.MajorAxisLength;
            %             Center(Centersize<3,:)=0;
            %             [~,maxid] = max(Center(:,2));
            app.pupilcenter(app.framei,:)=app.pupil.Centroid;
            %             app.thiscenter(app.framei,:)=Center(maxid,:);
            pupil = app.pupil;
            if isfield(pupil,'R')
                pupil= rmfield(pupil,'R');
            end
            app.allpupil = [app.allpupil;struct2table(pupil)];
            shift = 4;
            cornerx = [app.eye_dlc_output.Ex1(app.framei),app.eye_dlc_output.Ex3(app.framei)]-app.biasx;
            cornery = [app.eye_dlc_output.Ey1(app.framei),app.eye_dlc_output.Ey3(app.framei)+shift]-app.biasy;
            [app.Intersectpupil(app.framei,:),app.pupil_line(app.framei,:),~,app.pupil_to_corner_line_distance(app.framei)] ...
                = findprojectedvalues (app.pupil.Centroid,cornerx,cornery);
            %             [app.Intersectlight(app.framei,:),app.reflection_line(app.framei,:),app.eye_corner_line(app.framei,:),~] ...
            %                 = findprojectedvalues (Center(maxid,:),cornerx,cornery);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.data_folder_left = '/net/dk-server/afassihizakeri/Leftsidemovies';
            app.data_folder_right = '/net/dk-server/afassihizakeri/rightsidemovies';
            app.method = 'Original';
            app.Trialnumber = app.TrialEditField.Value;
            app.data_folder = app.data_folder_left;
            app.biasx= -4;
            app.biasy= -2;
            app.precentchange= 0.05;
            app.pupil_plot = nan;

        end

        % Value changed function: DividerThresholdEditField
        function DividerThresholdEditFieldValueChanged(app, event)
            app.threshold = app.DividerThresholdEditField.Value;
            app.update_plot();
        end

        % Value changed function: GaussianEditField
        function GaussianEditFieldValueChanged(app, event)
            app.gaussian = app.GaussianEditField.Value;
            app.update_plot();
        end

        % Button pushed function: ChangeLeftEyeCornerButton
        function ChangeLeftEyeCornerButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)
            [x, y] = ginput(1);
            app.eye_dlc_output.Ex1(app.framei) = x+app.biasx;
            app.eye_dlc_output.Ey1(app.framei) = y+app.biasy;
            app.update_plot()
        end

        % Button pushed function: ChangeRightEyeCornerButton
        function ChangeRightEyeCornerButtonPushed(app, event)
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)
            [x, y] = ginput(1);
            app.eye_dlc_output.Ex3(app.framei) = x+app.biasx;
            app.eye_dlc_output.Ey3(app.framei) = y+app.biasy;
            app.update_plot()
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            numberofiteration = 50;
            try
                checkparam =0;
                while checkparam==0
                    app.save_frame();
                    app.framei=app.framei+1;
                    app.ImageEye = PupilTracker.readindex(app.vL,double(app.framei));
                    app.update_plot()

                    if app.framei>3
                        pupilarea= ( pi.*(app.allpupil.MinorAxisLength.*app.allpupil.MajorAxisLength));
                        pupilarea=mean(pupilarea(end-2:end));
                        thisarea =  pi.*app.pupil.MajorAxisLength.*app.pupil.MinorAxisLength;
                        %                        inside this while try update_plot(app) and change
                        retry = 0;
                        Dummythreshold = app.threshold;
                        while thisarea>pupilarea*(1+app.precentchange)
                            retry= retry+1;
                            app.threshold=app.threshold-1;
                            [app.reflection,app.pupil,app.elipse,app.pupil_mask]=app.pupil_function(app.ImageEye,...
                                app.framei,app.eye_dlc_output,app.threshold,app.gaussian);
                            thisarea =  pi.*app.pupil.MajorAxisLength.*app.pupil.MinorAxisLength;
                            if retry>numberofiteration||Dummythreshold<1
                                app.threshold = Dummythreshold;
                                break
                            end
                        end

                        retry = 0;
                        Dummythreshold = app.threshold;
                        while thisarea<pupilarea*(1-app.precentchange)
                            retry= retry+1;
                            app.threshold=app.threshold+1;
                            [app.reflection,app.pupil,app.elipse,app.pupil_mask]=app.pupil_function(app.ImageEye,...
                                app.framei,app.eye_dlc_output,app.threshold,app.gaussian);
                            thisarea =  pi.*app.pupil.MajorAxisLength.*app.pupil.MinorAxisLength;
                            if retry>numberofiteration||Dummythreshold>150
                                app.threshold = Dummythreshold;
                                break
                            end
                        end
                        app.DividerThresholdEditField.Value = app.threshold;

                        if app.framei>4
                            hold(app.UIAxes,'off')
                            imagesc(app.ImageEye*4,'Parent', app.UIAxes)
                            hold(app.UIAxes,'on')
                            plot(app.UIAxes,app.elipse(1,:),app.elipse(2,:),'r')
                        end
                        if thisarea>pupilarea*(1+app.precentchange)||thisarea<pupilarea*(1-app.precentchange)
                            checkparam =1;
                            app.update_plot()
                        end


                    end
                end
            catch
                f = errordlg('end of trial');
            end
        end

        % Button pushed function: FinishTrialButton
        function FinishTrialButtonPushed(app, event)
            set(app.TrialEditField, 'Enable', 'on')
            set(app.StartButton, 'Enable', 'on')
            set(app.SelectDataPathButton, 'Enable', 'on')
            side = app.SideButtonGroup.SelectedObject.Text;
            save_object = getfield(app.Pupil,side);
            save_object.intersectpupil{app.Trialnumber+1}=app.Intersectpupil;
            save_object.intersectlight{app.Trialnumber+1}=app.Intersectlight;
            save_object.DY{app.Trialnumber+1}=app.pupil_to_corner_line_distance;
            save_object.EYEhorizontalaxis{app.Trialnumber+1}=app.eye_corner_line;
            save_object.Pupil{app.Trialnumber+1}=app.allpupil;
            save_object.pupilcenter{app.Trialnumber+1}=app.pupilcenter;
            save_object.Light{app.Trialnumber+1}=app.thiscenter;
            save_object.TearDuct{app.Trialnumber+1}=[app.eye_dlc_output.Ex1,app.eye_dlc_output.Ey1];
            save_object.EyeBack{app.Trialnumber+1}=[app.eye_dlc_output.Ex3,app.eye_dlc_output.Ey3];
            Pupil = app.get_new_pupil();
            Pupil = setfield(Pupil,side,save_object);
            save(fullfile(app.data_path,'Pupil.mat'),'Pupil');
            if strcmp(convertCharsToStrings(side),'Right')
                thisshit = find(cellfun(@numel,app.Pupil.Right.Pupil)==0);
                if any(thisshit>app.TrialEditField.Value+1)
                    app.TrialEditField.Value = thisshit(find(thisshit>app.TrialEditField.Value+1,1,'first'))-1;
                    app.Trialnumber  = app.TrialEditField.Value ;
                else
                    f = errordlg('no trial left');

                end
            elseif strcmp(convertCharsToStrings(side),'Left')
                thisshit = find(cellfun(@numel,app.Pupil.Left.Pupil)==0);
                if any(thisshit>app.TrialEditField.Value+1)

                    app.TrialEditField.Value = thisshit(find(thisshit>app.TrialEditField.Value+1,1,'first'))-1;
                    app.Trialnumber  = app.TrialEditField.Value ;
                else
                    f = errordlg('no trial left');

                end
            end


        end

        % Button pushed function: SelectDataPathButton
        function SelectDataPathButtonPushed(app, event)
            path = uigetdir(app.data_folder);
            if isa(path,'char')
                app.data_path = path;
                app.PathEditField.Value=app.data_path;

            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            try
                app.start_trial();
                set(app.TrialEditField, 'Enable', 'off')
                set(app.StartButton, 'Enable', 'off')
                set(app.SelectDataPathButton, 'Enable', 'off')
            catch exception
                if contains(exception.identifier,'FileNotFound')||contains(exception.identifier,'couldNotReadFile')
                    f = errordlg('not an experiment folder');
                elseif contains(exception.identifier,'badsubscript')
                    f = errordlg('not an valid trail');
                else
                    disp('unknown')
                end
            end
        end

        % Value changed function: TrialEditField
        function TrialEditFieldValueChanged(app, event)
            app.Trialnumber = app.TrialEditField.Value;
        end

        % Selection changed function: SideButtonGroup
        function SideButtonGroupSelectionChanged(app, event)
            side = app.SideButtonGroup.SelectedObject.Text;
            if strcmp(convertCharsToStrings(side),'Left')
                app.data_folder = app.data_folder_left;
            else
                app.data_folder = app.data_folder_right;
            end
        end

        % Selection changed function: MethodButtonGroup
        function MethodButtonGroupSelectionChanged(app, event)
            app.method = app.MethodButtonGroup.SelectedObject.Text;
            if strcmp(convertCharsToStrings(app.method),'Original')
                app.pupil_function = @ExtractEyeInfoNew;
            else
                app.pupil_function = @extract_eye_partial;
            end
            app.update_plot()
        end

        % Value changed function: PathEditField
        function PathEditFieldValueChanged(app, event)
            value = app.PathEditField.Value;

        end

        % Value changed function: Tracked_framesEditField
        function Tracked_framesEditFieldValueChanged(app, event)
            value = app.Tracked_framesEditField.Value;

        end

        % Value changed function: precentchangeEditField
        function precentchangeEditFieldValueChanged(app, event)
            app.precentchange =  app.precentchangeEditField.Value;

        end

        % Button pushed function: FinishtrialwithoutsavingButton
        function FinishtrialwithoutsavingButtonPushed(app, event)
            set(app.TrialEditField, 'Enable', 'on')
            set(app.StartButton, 'Enable', 'on')
            set(app.SelectDataPathButton, 'Enable', 'on')
            side = app.SideButtonGroup.SelectedObject.Text;
            if strcmp(convertCharsToStrings(side),'Right')
                thisshit = find(cellfun(@numel,app.Pupil.Right.Pupil)==0);
                if any(thisshit>app.TrialEditField.Value+1)
                    app.TrialEditField.Value = thisshit(find(thisshit>app.TrialEditField.Value+1,1,'first'))-1;
                    app.Trialnumber  = app.TrialEditField.Value ;
                else
                    f = errordlg('no trial left');

                end
            elseif strcmp(convertCharsToStrings(side),'Left')
                thisshit = find(cellfun(@numel,app.Pupil.Left.Pupil)==0);
                if any(thisshit>app.TrialEditField.Value+1)

                    app.TrialEditField.Value = thisshit(find(thisshit>app.TrialEditField.Value+1,1,'first'))-1;
                    app.Trialnumber  = app.TrialEditField.Value ;
                else
                    f = errordlg('no trial left');

                end
            end

        end

        % Button pushed function: DrawPupilButton
        function DrawPupilButtonPushed(app, event)
            app.pupil_plot = PupilPlotter.plot_pupil(app.ImageEye*10,app.pupil_plot,app.UIAxes);
            roi = drawpolygon(app.UIAxes);
            app.pupil_mask = createMask(roi);
            [app.pupil,~] = PupilTracker.fit_pupil_from_pupil_mask(app.pupil_mask);
            app.elipse = PupilTracker.get_elipse_from_fit_parameter(...
                app.pupil.MajorAxisLength/2,app.pupil.MinorAxisLength/2,...
                app.pupil.Centroid',app.pupil.Orientation);
            PupilPlotter.plot_pupil_fit(app.UIAxes,app.ImageEye*10,app.elipse,...
                app.pupil,app.eye_dlc_output,app.framei)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ChangeLeftEyeCornerButton = uibutton(app.UIFigure, 'push');
            app.ChangeLeftEyeCornerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeLeftEyeCornerButtonPushed, true);
            app.ChangeLeftEyeCornerButton.Text = 'Change Left Eye Corner';
            app.ChangeRightEyeCornerButton = uibutton(app.UIFigure, 'push');
            app.ChangeRightEyeCornerButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeRightEyeCornerButtonPushed, true);
            app.ChangeRightEyeCornerButton.Text = 'Change Right Eye Corner';
            app.DividerThresholdLabel = uilabel(app.UIFigure);
            app.DividerThresholdLabel.Text = {'Divider /'; 'Threshold'};
            app.DividerThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.DividerThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @DividerThresholdEditFieldValueChanged, true);
            app.DividerThresholdEditField.Value = 15;
            app.GaussianEditFieldLabel = uilabel(app.UIFigure);
            app.GaussianEditFieldLabel.Text = 'Gaussian';
            app.GaussianEditField = uieditfield(app.UIFigure, 'numeric');
            app.GaussianEditField.ValueChangedFcn = createCallbackFcn(app, @GaussianEditFieldValueChanged, true);
            app.GaussianEditField.Value = 10;
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.FinishTrialButton = uibutton(app.UIFigure, 'push');
            app.FinishTrialButton.ButtonPushedFcn = createCallbackFcn(app, @FinishTrialButtonPushed, true);
            app.FinishTrialButton.Text = 'Finish Trial';
            app.SelectDataPathButton = uibutton(app.UIFigure, 'push');
            app.SelectDataPathButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataPathButtonPushed, true);
            app.SelectDataPathButton.Text = 'Select Data Path';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrialEditFieldLabel = uilabel(app.UIFigure);
            app.TrialEditFieldLabel.Text = 'Trial';
            app.TrialEditField = uieditfield(app.UIFigure, 'numeric');
            app.TrialEditField.ValueChangedFcn = createCallbackFcn(app, @TrialEditFieldValueChanged, true);
            app.TrialEditField.Value = 1;
            app.SideButtonGroup = uibuttongroup(app.UIFigure);
            app.SideButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SideButtonGroupSelectionChanged, true);
            app.SideButtonGroup.Title = 'Side';
            app.LeftButton = uiradiobutton(app.SideButtonGroup);
            app.LeftButton.Text = 'Left';
            app.LeftButton.Value = true;
            app.RightButton = uiradiobutton(app.SideButtonGroup);
            app.RightButton.Text = 'Right';
            app.MethodButtonGroup = uibuttongroup(app.UIFigure);
            app.MethodButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MethodButtonGroupSelectionChanged, true);
            app.MethodButtonGroup.Title = 'Method';
            app.OriginalButton = uiradiobutton(app.MethodButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.NewButton = uiradiobutton(app.MethodButtonGroup);
            app.NewButton.Text = 'New';
            app.precentchangeEditFieldLabel = uilabel(app.UIFigure);
            app.precentchangeEditFieldLabel.Text = 'precentchange';
            app.precentchangeEditField = uieditfield(app.UIFigure, 'numeric');
            app.precentchangeEditField.ValueChangedFcn = createCallbackFcn(app, @precentchangeEditFieldValueChanged, true);
            app.precentchangeEditField.Value = 0.05;
            app.PathEditFieldLabel = uilabel(app.UIFigure);
            app.PathEditFieldLabel.Text = 'Path';
            app.PathEditField = uieditfield(app.UIFigure, 'text');
            app.PathEditField.ValueChangedFcn = createCallbackFcn(app, @PathEditFieldValueChanged, true);
            app.Tracked_framesEditFieldLabel = uilabel(app.UIFigure);
            app.Tracked_framesEditFieldLabel.Text = 'Tracked_frames';
            app.Tracked_framesEditField = uieditfield(app.UIFigure, 'numeric');
            app.Tracked_framesEditField.ValueChangedFcn = createCallbackFcn(app, @Tracked_framesEditFieldValueChanged, true);
            app.FinishtrialwithoutsavingButton = uibutton(app.UIFigure, 'push');
            app.FinishtrialwithoutsavingButton.ButtonPushedFcn = createCallbackFcn(app, @FinishtrialwithoutsavingButtonPushed, true);
            app.FinishtrialwithoutsavingButton.Text = 'Finish trial without saving ';
            app.DrawPupilButton = uibutton(app.UIFigure, 'push');
            app.DrawPupilButton.ButtonPushedFcn = createCallbackFcn(app, @DrawPupilButtonPushed, true);
            app.DrawPupilButton.Text = 'Draw Pupil';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PupilAdjustment_VER2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end